classdef CNNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CNNUIFigure                matlab.ui.Figure
        SearchimageButton          matlab.ui.control.Button
        TemplateDropDownLabel      matlab.ui.control.Label
        TemplateDropDown           matlab.ui.control.DropDown
        InitialstateDropDownLabel  matlab.ui.control.Label
        InitialstateDropDown       matlab.ui.control.DropDown
        SearchinitialstateButton   matlab.ui.control.Button
        A00                        matlab.ui.control.NumericEditField
        A01                        matlab.ui.control.NumericEditField
        A02                        matlab.ui.control.NumericEditField
        A10                        matlab.ui.control.NumericEditField
        A11                        matlab.ui.control.NumericEditField
        A12                        matlab.ui.control.NumericEditField
        A20                        matlab.ui.control.NumericEditField
        A21                        matlab.ui.control.NumericEditField
        A22                        matlab.ui.control.NumericEditField
        B00                        matlab.ui.control.NumericEditField
        B01                        matlab.ui.control.NumericEditField
        B02                        matlab.ui.control.NumericEditField
        B10                        matlab.ui.control.NumericEditField
        B11                        matlab.ui.control.NumericEditField
        B12                        matlab.ui.control.NumericEditField
        B20                        matlab.ui.control.NumericEditField
        B21                        matlab.ui.control.NumericEditField
        B22                        matlab.ui.control.NumericEditField
        ALabel                     matlab.ui.control.Label
        BLabel                     matlab.ui.control.Label
        IterationsSpinnerLabel     matlab.ui.control.Label
        IterationsSpinner          matlab.ui.control.Spinner
        zSpinnerLabel              matlab.ui.control.Label
        zSpinner                   matlab.ui.control.Spinner
        dt                         matlab.ui.control.NumericEditField
        dtLabel                    matlab.ui.control.Label
        RunCNNButton               matlab.ui.control.Button
        msLabel                    matlab.ui.control.Label
        DurationEditFieldLabel     matlab.ui.control.Label
        DurationEditField          matlab.ui.control.NumericEditField
        TabGroup2                  matlab.ui.container.TabGroup
        InputTab                   matlab.ui.container.Tab
        UIAxes                     matlab.ui.control.UIAxes
        InitialstateTab            matlab.ui.container.Tab
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function [x,U] = appendBoundaries(~,x,U,r)
            
            for i=1:r
                [M,N] = size(U);
                x=[-ones(1,N);x;-ones(1,N)];
                U=[-ones(1,N);U;-ones(1,N)];
                NewCol = -ones(M+2,1);
                NewColOnes = -ones(M+2,1);
                x=[NewCol x NewCol];
                U=[NewColOnes U NewColOnes];
            end
        end
        function [x,U] = appendBoundaries2(~,x,U,r)
            
            for i=1:r
                [M,N] = size(U);
                x=[zeros(1,N);x;zeros(1,N)];
                U=[zeros(1,N);U;zeros(1,N)];
                NewCol = zeros(M+2,1);
                NewColOnes = zeros(M+2,1);
                x=[NewCol x NewCol];
                U=[NewColOnes U NewColOnes];
            end
            % Now actual size is M+2*r N+2*r
        end
        
        % Edit gene fields from TemplateDropDown
        function setParameters(app)
            switch app.TemplateDropDown.Value
                case "Figure reconstruction A"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=8;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=8;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=6;
                    
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Figure reconstruction B"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=8;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=2;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                case "Concave-filling A"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=8;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=1;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Concave-filling B"
                    % A
                    app.A00.Value=1;app.A01.Value=1;app.A02.Value=1;
                    app.A10.Value=1;app.A11.Value=8;app.A12.Value=1;
                    app.A20.Value=1;app.A21.Value=1;app.A22.Value=1;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=8;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Hole-filling A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=5;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-1;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Hole-filling B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=6;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Filled contour extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=2;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-4;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Filled contour extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=2;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-4.5;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Selected objects extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=7;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-1;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Selected objects extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=7.5;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-1.5;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Face-vase-illusion A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=-6;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Face-vase-illusion B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=-7;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-1;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Solid black framed area extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-2;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Solid black framed area extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=-0.4;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-2;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Global connectivity detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=-1;app.B02.Value=0;
                    app.B10.Value=-1;app.B11.Value=6;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=-1;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-8;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Global connectivity detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=1;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=4;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=1;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=-1;app.B02.Value=0;
                    app.B10.Value=-1;app.B11.Value=5;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=-1;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-7;
                    
                    app.InitialstateDropDown.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Point removal A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=1;app.B01.Value=1;app.B02.Value=1;
                    app.B10.Value=1;app.B11.Value=8;app.B12.Value=1;
                    app.B20.Value=1;app.B21.Value=1;app.B22.Value=1;
                    % z
                    app.zSpinner.Value=-2;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point removal B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=1;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=4;app.B12.Value=1;
                    app.B20.Value=0;app.B21.Value=1;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-2;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point extraction A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=1;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.zSpinner.Value=-9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point extraction B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=-1;app.B02.Value=0;
                    app.B10.Value=-1;app.B11.Value=1;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=-1;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Edge detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.zSpinner.Value=-2;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Edge detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.zSpinner.Value=-0.5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Convex corner detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=4;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.zSpinner.Value=-4;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Convex corner detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8;app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.zSpinner.Value=-8.5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Erosion A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=1;app.B01.Value=1;app.B02.Value=1;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=1;app.B21.Value=1;app.B22.Value=1;
                    % z
                    app.zSpinner.Value=-9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Erosion B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=1;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=0;app.B21.Value=1;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Dilation A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=1;app.B01.Value=1;app.B02.Value=1;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=1;app.B21.Value=1;app.B22.Value=1;
                    % z
                    app.zSpinner.Value=9;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Dilation B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=1;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=1;
                    app.B20.Value=0;app.B21.Value=1;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=5;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Right edge detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=-1;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-3;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Right edge detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=1;app.B11.Value=1;app.B12.Value=-2;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=-4;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Shadow projection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=2;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=2;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Shadow projection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=1;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=1;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Horizontal hole detection A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=1;app.A11.Value=2;app.A12.Value=-1;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Horizontal hole detection B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=2;app.A11.Value=2;app.A12.Value=-2;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0;app.B02.Value=0;
                    app.B10.Value=0;app.B11.Value=0;app.B12.Value=0;
                    app.B20.Value=0;app.B21.Value=0;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Inverse half-toning A"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=0;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0.08;app.B01.Value=0.08;app.B02.Value=0.08;
                    app.B10.Value=0.08;app.B11.Value=0.36;app.B12.Value=0.08;
                    app.B20.Value=0.08;app.B21.Value=0.08;app.B22.Value=0.08;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Inverse half-toning B"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=0;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0.18;app.B02.Value=0;
                    app.B10.Value=0.18;app.B11.Value=0.38;app.B12.Value=0.18;
                    app.B20.Value=0;app.B21.Value=0.18;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Half-toning A"
                    % A
                    app.A00.Value=-0.07;app.A01.Value=-0.1;app.A02.Value=-0.07;
                    app.A10.Value=-0.1;app.A11.Value=3.68;app.A12.Value=-0.1;
                    app.A20.Value=-0.07;app.A21.Value=-0.1;app.A22.Value=-0.07;
                    % B
                    app.B00.Value=0.07;app.B01.Value=0.1;app.B02.Value=0.07;
                    app.B10.Value=0.1;app.B11.Value=0.32;app.B12.Value=0.1;
                    app.B20.Value=0.07;app.B21.Value=0.1;app.B22.Value=0.07;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Half-toning B"
                    % A
                    app.A00.Value=0;app.A01.Value=-0.18;app.A02.Value=0;
                    app.A10.Value=-0.18;app.A11.Value=3.72;app.A12.Value=-0.18;
                    app.A20.Value=0;app.A21.Value=-0.18;app.A22.Value=0;
                    % B
                    app.B00.Value=0;app.B01.Value=0.18;app.B02.Value=0;
                    app.B10.Value=0.18;app.B11.Value=0.28;app.B12.Value=0.18;
                    app.B20.Value=0;app.B21.Value=0.18;app.B22.Value=0;
                    % z
                    app.zSpinner.Value=0;
                    
                    app.InitialstateDropDown.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Edge detection"
                    % A
                    app.A00.Value=0;app.A01.Value=0;app.A02.Value=0;
                    app.A10.Value=0;app.A11.Value=2;app.A12.Value=0;
                    app.A20.Value=0;app.A21.Value=0;app.A22.Value=0;
                    % B
                    app.B00.Value=-1;app.B01.Value=-1;app.B02.Value=-1;
                    app.B10.Value=-1;app.B11.Value=8; app.B12.Value=-1;
                    app.B20.Value=-1;app.B21.Value=-1;app.B22.Value=-1;
                    % z
                    app.zSpinner.Value=-0.5;
                    
                    app.InitialstateDropDown.Value="All 0";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
            end
            
        end
        
        function setParameters2(app)
            switch app.TemplateDropDown_2.Value
                case "Figure reconstruction A"
                    % A
                    app.A00_2.Value=1;app.A01_2.Value=1;app.A02_2.Value=1;
                    app.A10_2.Value=1;app.A11_2.Value=8;app.A12_2.Value=1;
                    app.A20_2.Value=1;app.A21_2.Value=1;app.A22_2.Value=1;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=8;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=6;
                    
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Figure reconstruction B"
                    % A
                    app.A00_2.Value=1;app.A01_2.Value=1;app.A02_2.Value=1;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=1;app.A21_2.Value=1;app.A22_2.Value=1;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=8;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=2;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                case "Concave-filling A"
                    % A
                    app.A00_2.Value=1;app.A01_2.Value=1;app.A02_2.Value=1;
                    app.A10_2.Value=1;app.A11_2.Value=8;app.A12_2.Value=1;
                    app.A20_2.Value=1;app.A21_2.Value=1;app.A22_2.Value=1;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=1;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=9;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Concave-filling B"
                    % A
                    app.A00_2.Value=1;app.A01_2.Value=1;app.A02_2.Value=1;
                    app.A10_2.Value=1;app.A11_2.Value=8;app.A12_2.Value=1;
                    app.A20_2.Value=1;app.A21_2.Value=1;app.A22_2.Value=1;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=0;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=8;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Hole-filling A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=5;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-1;
                    
                    app.InitialstateDropDown_2.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Hole-filling B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=6;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Filled contour extraction A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=2;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-4;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Filled contour extraction B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=2;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-4.5;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Selected objects extraction A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=7;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-1;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off');
                    
                case "Selected objects extraction B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=7.5;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-1.5;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Face-vase-illusion A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=-6;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Face-vase-illusion B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=-7;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-1;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Solid black framed area extraction A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=0;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-2;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                    
                case "Solid black framed area extraction B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=-0.4;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-2;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Global connectivity detection A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=-1;app.B02_2.Value=0;
                    app.B10_2.Value=-1;app.B11_2.Value=6;app.B12_2.Value=-1;
                    app.B20_2.Value=0;app.B21_2.Value=-1;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-8;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Global connectivity detection B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=1;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=4;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=1;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=-1;app.B02_2.Value=0;
                    app.B10_2.Value=-1;app.B11_2.Value=5;app.B12_2.Value=-1;
                    app.B20_2.Value=0;app.B21_2.Value=-1;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-7;
                    
                    app.InitialstateDropDown_2.Value="Custom image";
                    set(app.SearchinitialstateButton, 'Enable', 'on');
                    set(app.SearchinitialstateButton, 'Visible', 'on');
                    %set(app.RunCNNButton, 'Enable', 'off')
                case "Point removal A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=1;app.B01_2.Value=1;app.B02_2.Value=1;
                    app.B10_2.Value=1;app.B11_2.Value=8;app.B12_2.Value=1;
                    app.B20_2.Value=1;app.B21_2.Value=1;app.B22_2.Value=1;
                    % z
                    app.zSpinner_2.Value=-2;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point removal B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=1;app.B02_2.Value=0;
                    app.B10_2.Value=1;app.B11_2.Value=4;app.B12_2.Value=1;
                    app.B20_2.Value=0;app.B21_2.Value=1;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-2;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point extraction A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=-1;app.B01_2.Value=-1;app.B02_2.Value=-1;
                    app.B10_2.Value=-1;app.B11_2.Value=1;app.B12_2.Value=-1;
                    app.B20_2.Value=-1;app.B21_2.Value=-1;app.B22_2.Value=-1;
                    % z
                    app.zSpinner_2.Value=-9;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Point extraction B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=-1;app.B02_2.Value=0;
                    app.B10_2.Value=-1;app.B11_2.Value=1;app.B12_2.Value=-1;
                    app.B20_2.Value=0;app.B21_2.Value=-1;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-5;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Edge detection A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=-1;app.B01_2.Value=-1;app.B02_2.Value=-1;
                    app.B10_2.Value=-1;app.B11_2.Value=8;app.B12_2.Value=-1;
                    app.B20_2.Value=-1;app.B21_2.Value=-1;app.B22_2.Value=-1;
                    % z
                    app.zSpinner_2.Value=-2;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                case "Edge detection B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=-1;app.B01_2.Value=-1;app.B02_2.Value=-1;
                    app.B10_2.Value=-1;app.B11_2.Value=8;app.B12_2.Value=-1;
                    app.B20_2.Value=-1;app.B21_2.Value=-1;app.B22_2.Value=-1;
                    % z
                    app.zSpinner_2.Value=-0.5;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Convex corner detection A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=-1;app.B01_2.Value=-1;app.B02_2.Value=-1;
                    app.B10_2.Value=-1;app.B11_2.Value=4;app.B12_2.Value=-1;
                    app.B20_2.Value=-1;app.B21_2.Value=-1;app.B22_2.Value=-1;
                    % z
                    app.zSpinner_2.Value=-4;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Convex corner detection B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=-1;app.B01_2.Value=-1;app.B02_2.Value=-1;
                    app.B10_2.Value=-1;app.B11_2.Value=8;app.B12_2.Value=-1;
                    app.B20_2.Value=-1;app.B21_2.Value=-1;app.B22_2.Value=-1;
                    % z
                    app.zSpinner_2.Value=-8.5;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Erosion A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=1;app.B01_2.Value=1;app.B02_2.Value=1;
                    app.B10_2.Value=1;app.B11_2.Value=1;app.B12_2.Value=1;
                    app.B20_2.Value=1;app.B21_2.Value=1;app.B22_2.Value=1;
                    % z
                    app.zSpinner_2.Value=-9;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Erosion B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=1;app.B02_2.Value=0;
                    app.B10_2.Value=1;app.B11_2.Value=1;app.B12_2.Value=1;
                    app.B20_2.Value=0;app.B21_2.Value=1;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-5;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Dilation A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=1;app.B01_2.Value=1;app.B02_2.Value=1;
                    app.B10_2.Value=1;app.B11_2.Value=1;app.B12_2.Value=1;
                    app.B20_2.Value=1;app.B21_2.Value=1;app.B22_2.Value=1;
                    % z
                    app.zSpinner_2.Value=9;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Dilation B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=1;app.B02_2.Value=0;
                    app.B10_2.Value=1;app.B11_2.Value=1;app.B12_2.Value=1;
                    app.B20_2.Value=0;app.B21_2.Value=1;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=5;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Right edge detection A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=1;app.B11_2.Value=1;app.B12_2.Value=-1;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-3;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Right edge detection B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=1;app.B11_2.Value=1;app.B12_2.Value=-2;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=-4;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Shadow projection A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=2;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=2;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Shadow projection B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=1;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=1;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="All 1";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Horizontal hole detection A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=1;app.A11_2.Value=2;app.A12_2.Value=-1;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=0;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Horizontal hole detection B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=2;app.A11_2.Value=2;app.A12_2.Value=-2;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0;app.B02_2.Value=0;
                    app.B10_2.Value=0;app.B11_2.Value=0;app.B12_2.Value=0;
                    app.B20_2.Value=0;app.B21_2.Value=0;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Inverse half-toning A"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=0;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0.08;app.B01_2.Value=0.08;app.B02_2.Value=0.08;
                    app.B10_2.Value=0.08;app.B11_2.Value=0.36;app.B12_2.Value=0.08;
                    app.B20_2.Value=0.08;app.B21_2.Value=0.08;app.B22_2.Value=0.08;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Inverse half-toning B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=0;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0.18;app.B02_2.Value=0;
                    app.B10_2.Value=0.18;app.B11_2.Value=0.38;app.B12_2.Value=0.18;
                    app.B20_2.Value=0;app.B21_2.Value=0.18;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Half-toning A"
                    % A
                    app.A00_2.Value=-0.07;app.A01_2.Value=-0.1;app.A02_2.Value=-0.07;
                    app.A10_2.Value=-0.1;app.A11_2.Value=3.68;app.A12_2.Value=-0.1;
                    app.A20_2.Value=-0.07;app.A21_2.Value=-0.1;app.A22_2.Value=-0.07;
                    % B
                    app.B00_2.Value=0.07;app.B01_2.Value=0.1;app.B02_2.Value=0.07;
                    app.B10_2.Value=0.1;app.B11_2.Value=0.32;app.B12_2.Value=0.1;
                    app.B20_2.Value=0.07;app.B21_2.Value=0.1;app.B22_2.Value=0.07;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Half-toning B"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=-0.18;app.A02_2.Value=0;
                    app.A10_2.Value=-0.18;app.A11_2.Value=3.72;app.A12_2.Value=-0.18;
                    app.A20_2.Value=0;app.A21_2.Value=-0.18;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=0;app.B01_2.Value=0.18;app.B02_2.Value=0;
                    app.B10_2.Value=0.18;app.B11_2.Value=0.28;app.B12_2.Value=0.18;
                    app.B20_2.Value=0;app.B21_2.Value=0.18;app.B22_2.Value=0;
                    % z
                    app.zSpinner_2.Value=0;
                    
                    app.InitialstateDropDown_2.Value="Same image";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
                    
                case "Edge detection"
                    % A
                    app.A00_2.Value=0;app.A01_2.Value=0;app.A02_2.Value=0;
                    app.A10_2.Value=0;app.A11_2.Value=2;app.A12_2.Value=0;
                    app.A20_2.Value=0;app.A21_2.Value=0;app.A22_2.Value=0;
                    % B
                    app.B00_2.Value=-1;app.B01_2.Value=-1;app.B02_2.Value=-1;
                    app.B10_2.Value=-1;app.B11_2.Value=8; app.B12_2.Value=-1;
                    app.B20_2.Value=-1;app.B21_2.Value=-1;app.B22_2.Value=-1;
                    % z
                    app.zSpinner_2.Value=-0.5;
                    
                    app.InitialstateDropDown_2.Value="All 0";
                    %set(app.SearchinitialstateButton, 'Enable', 'off');
                    %set(app.SearchinitialstateButton, 'Visible', 'off');
                    set(app.RunCNNButton, 'Enable', 'on');
            end
            
        end
        
        
        % Get genes from edit fields
        function [A,B,z]=getGenes(app)
            A=[ app.A00.Value app.A01.Value app.A02.Value;
                app.A10.Value app.A11.Value app.A12.Value;
                app.A20.Value app.A21.Value app.A22.Value];
            
            B=[ app.B00.Value app.B01.Value app.B02.Value;
                app.B10.Value app.B11.Value app.B12.Value;
                app.B20.Value app.B21.Value app.B22.Value];
            
            z=app.zSpinner.Value;
        end
        function [A,B,z]=getGenes2(app)
            A=[ app.A00_2.Value app.A01_2.Value app.A02_2.Value;
                app.A10_2.Value app.A11_2.Value app.A12_2.Value;
                app.A20_2.Value app.A21_2.Value app.A22_2.Value];
            
            B=[ app.B00_2.Value app.B01_2.Value app.B02_2.Value;
                app.B10_2.Value app.B11_2.Value app.B12_2.Value;
                app.B20_2.Value app.B21_2.Value app.B22_2.Value];
            
            z=app.zSpinner.Value;
        end


    end
    

    methods (Access = private)

        % Button pushed function: SearchimageButton
        function SearchimageButtonPushed(app, event)
            global image
            global gray
            try
                [filename, pathname]=uigetfile({'*.jpg; *.png; *.jpeg'},'File Selector');
                fullpathname = strcat(pathname,filename);
                image=imread(fullpathname);
                gray=rgb2gray(image);
                imshow(gray,'Parent', app.UIAxes);
            catch
                %warning('No image selected');
                
            end
        end

        % Button pushed function: RunCNNButton
        function RunCNNButtonPushed(app, event)
                        
            % Settings
            set(app.RunCNNButton, 'Enable', 'off');
            set(app.RunCNNButton, 'Visible', 'off');

            global gray;
            global initial;
            %global image;

            U = gpuArray(im2double(gray));
            U = -(U * 2 - 1);          % [0-1] a [-1,1]: negre: +1 blanc: -1
            [M,N] = size(U);            % Original input dimensions
            
            now = tic();
            %% Prepare initial state
            switch app.InitialstateDropDown.Value
                case "All 0"
                    X=zeros(M,N);
                    xrev=(1-X)/2;
                    imshow(xrev,'Parent', app.UIAxes_4);
                case "All 1"
                    X=ones(M,N);
                    xrev=(1-X)/2;
                    imshow(xrev,'Parent', app.UIAxes_4);
                case "Same image"
                    X=U;
                    xrev=(1-X)/2;
                    imshow(xrev,'Parent', app.UIAxes_4);
                case "Custom image"
                    % initial image to double
                    Idouble=im2double(initial);
                    % Convert to interval [-1,1] white: -1   black: +1
                    Idouble=-(Idouble*2-1);
                    X=Idouble;
            end
            
            
            % Get parameters
            [A,B,z]=getGenes(app);
            
            % Radius
            r=1;
            
            % Afegir files i columnes de zeros a x i U
            
            X = padarray(X,[r, r],-1,'both');
            U = padarray(U,[r, r],-1,'both');

            
            % CNN
            global iterations;
            iterations=app.IterationsSpinner.Value;
            global dt;
            dt=app.dt.Value;
            alpha=1-dt;
            A = gpuArray(dt*A);
            B = gpuArray(B);
            X = gpuArray(X);
            Y = gpuArray(X);
            Y2 = gpuArray(X);
            S = gpuArray(dt*(conv2(U,B,'same')+z));

            for it=1:iterations
                X=X*alpha+conv2(Y,A,'same')+S;
                Y=0.5*(abs(X+1)-abs(X-1));
            end

            %app.StoppedatitEditField.Value=it;
            duration= toc(now)*1000;
            app.DurationEditField.Value=duration;
            
            
            % Revert [-1,+1] to [0-1]: white: 0  black: 1
            global result;
            result=gather(Y);
%           result=gather(X);
            result=(1-result)/2;
            % Delete extra boundaries
            [M,N]=size(result);
            result(1+M-r:M,:)=[]; result(1:r,:)=[]; % First and last rows
            result(:,1+N-r:N)=[]; result(:,1:r)=[]; % First and last columns
            
            % Show result
            imshow(result,'Parent', app.UIAxes_3);
            imwrite(result, 'result.png');

            %set(app.RunCNNButton, 'BackgroundColor', '1.00,0.00,0.00');
            
            
            % Final settings
            set(app.RunCNNButton, 'Enable', 'on');
            %set(app.StopButton, 'Visible', 'off');
            set(app.RunCNNButton, 'Visible', 'on');
            %set(app.RunningLabel, 'Visible', 'off');
        end

        % Value changed function: IterationsSpinner
        function IterationsSpinnerValueChanged(app, event)
            value = app.IterationsSpinner.Value;
            global iterations;
            iterations=value;
        end

        % Value changed function: TemplateDropDown
        function TemplateDropDownValueChanged(app, event)
            %value = app.TemplateDropDown.Value;
            setParameters(app);
        end

        % Button pushed function: SearchinitialstateButton
        function SearchinitialstateButtonPushed(app, event)
                        global initial
            try
                [filename, pathname]=uigetfile({'*.jpg; *png'},'File Selector');
                fullpathname = strcat(pathname,filename);
                initial=imread(fullpathname);
                initial=rgb2gray(initial);
                imshow(initial,'Parent', app.UIAxes_4);
                set(app.RunCNNButton, 'Enable', 'on');
            catch
                %warning('No image selected');
            end
        end

        % Value changed function: dt
        function dtValueChanged(app, event)

            value = app.dt.Value;
            global dt;
            dt=value;
            
        end

        % Value changed function: zSpinner
        function zSpinnerValueChanged(app, event)
            value = app.zSpinner.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CNNUIFigure = uifigure;
            app.CNNUIFigure.Name = 'CNN';
            app.SearchimageButton = uibutton(app.CNNUIFigure, 'push');
            app.SearchimageButton.ButtonPushedFcn = createCallbackFcn(app, @SearchimageButtonPushed, true);
            app.SearchimageButton.Text = 'Search image';
            app.TemplateDropDownLabel = uilabel(app.CNNUIFigure);
            app.TemplateDropDownLabel.Text = 'Template';
            app.TemplateDropDown = uidropdown(app.CNNUIFigure);
            app.TemplateDropDown.Items = {'----------------', 'Figure reconstruction A', 'Figure reconstruction B', 'Concave-filling A', 'Concave-filling B', 'Hole-filling A', 'Hole-filling B', 'Filled contour extraction A', 'Filled contour extraction B', 'Selected objects extraction A', 'Selected objects extraction B', 'Face-vase-illusion A', 'Face-vase-illusion B', 'Solid black framed area extraction A', 'Solid black framed area extraction B', 'Global connectivity detection A', 'Global connectivity detection B', 'Point removal A', 'Point removal B', 'Point extraction A', 'Point extraction B', 'Edge detection A', 'Edge detection B', 'Convex corner detection A', 'Convex corner detection B', 'Erosion A', 'Erosion B', 'Dilation A', 'Dilation B', 'Right edge detection A', 'Right edge detection B', 'Shadow projection A', 'Shadow projection B', 'Horizontal hole detection A', 'Horizontal hole detection B', 'Inverse half-toning A', 'Inverse half-toning B', 'Half-toning A', 'Half-toning B', 'Edge detection'};
            app.TemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @TemplateDropDownValueChanged, true);
            app.TemplateDropDown.Value = '----------------';
            app.InitialstateDropDownLabel = uilabel(app.CNNUIFigure);
            app.InitialstateDropDownLabel.Text = 'Initial state';
            app.InitialstateDropDown = uidropdown(app.CNNUIFigure);
            app.InitialstateDropDown.Items = {'All 0', 'All 1', 'Same image', 'Custom image'};
            app.InitialstateDropDown.Value = 'All 0';
            app.SearchinitialstateButton = uibutton(app.CNNUIFigure, 'push');
            app.SearchinitialstateButton.ButtonPushedFcn = createCallbackFcn(app, @SearchinitialstateButtonPushed, true);
            app.SearchinitialstateButton.Text = 'Search initial state';
            app.A00 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A01 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A02 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A10 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A11 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A12 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A20 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A21 = uieditfield(app.CNNUIFigure, 'numeric');
            app.A22 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B00 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B01 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B02 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B10 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B11 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B12 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B20 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B21 = uieditfield(app.CNNUIFigure, 'numeric');
            app.B22 = uieditfield(app.CNNUIFigure, 'numeric');
            app.ALabel = uilabel(app.CNNUIFigure);
            app.ALabel.Text = 'A =';
            app.BLabel = uilabel(app.CNNUIFigure);
            app.BLabel.Text = 'B =';
            app.IterationsSpinnerLabel = uilabel(app.CNNUIFigure);
            app.IterationsSpinnerLabel.Text = 'Iterations';
            app.IterationsSpinner = uispinner(app.CNNUIFigure);
            app.IterationsSpinner.ValueChangedFcn = createCallbackFcn(app, @IterationsSpinnerValueChanged, true);
            app.IterationsSpinner.Value = 100;
            app.zSpinnerLabel = uilabel(app.CNNUIFigure);
            app.zSpinnerLabel.Text = 'z = ';
            app.zSpinner = uispinner(app.CNNUIFigure);
            app.zSpinner.Step = 0.1;
            app.zSpinner.ValueChangedFcn = createCallbackFcn(app, @zSpinnerValueChanged, true);
            app.dt = uieditfield(app.CNNUIFigure, 'numeric');
            app.dt.ValueChangedFcn = createCallbackFcn(app, @dtValueChanged, true);
            app.dt.Value = 0.1;
            app.dtLabel = uilabel(app.CNNUIFigure);
            app.dtLabel.Text = 'dt =';
            app.RunCNNButton = uibutton(app.CNNUIFigure, 'push');
            app.RunCNNButton.ButtonPushedFcn = createCallbackFcn(app, @RunCNNButtonPushed, true);
            app.RunCNNButton.Text = 'Run CNN';
            app.msLabel = uilabel(app.CNNUIFigure);
            app.msLabel.Text = 'ms';
            app.DurationEditFieldLabel = uilabel(app.CNNUIFigure);
            app.DurationEditFieldLabel.Text = 'Duration:';
            app.DurationEditField = uieditfield(app.CNNUIFigure, 'numeric');
            app.DurationEditField.Editable = 'off';
            app.TabGroup2 = uitabgroup(app.CNNUIFigure);
            app.InputTab = uitab(app.TabGroup2);
            app.InputTab.Title = 'Input';
            app.UIAxes = uiaxes(app.InputTab);
            title(app.UIAxes, 'Input')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.InitialstateTab = uitab(app.TabGroup2);
            app.InitialstateTab.Title = 'Initial state';
            app.UIAxes_4 = uiaxes(app.InitialstateTab);
            title(app.UIAxes_4, 'Initial state')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_3 = uiaxes(app.CNNUIFigure);
            title(app.UIAxes_3, 'Output')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = CNNN

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CNNUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CNNUIFigure)
        end
    end
end